classdef imageProcessingProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        photoAnalysis        matlab.ui.Figure
        ChannelButtonGroup   matlab.ui.container.ButtonGroup
        TransButton          matlab.ui.control.ToggleButton
        RFPButton            matlab.ui.control.ToggleButton
        GFPButton            matlab.ui.control.ToggleButton
        UIAxes               matlab.ui.control.UIAxes
        ContrastSliderLabel  matlab.ui.control.Label
        ContrastSlider       matlab.ui.control.Slider
        ImportFileButton     matlab.ui.control.Button
        TimeSliderLabel      matlab.ui.control.Label
        TimeSlider           matlab.ui.control.Slider
        SelectCellButton     matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            importdata(bfopen())
                plot(app.UIAxes, importdata());
            
            %bfopen opens finder to select file
            % show data_cell{1,1}
                %inside {1,1} are rows 1-21, which have the different time points, z axes and channels for plain images, time points will be 1-441
               
                
        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Selection changed function: ChannelButtonGroup
        function ChannelButtonGroupSelectionChanged(app, event)
           % selectedButton = app.ChannelButtonGroup.SelectedObject;
            %    for (app.ChannelButtonGroup, app.TransButton = "TRUE");
             %         imshow(data_cell{1,1}{1,1})
              %  else if (app.ChannelButtonGroup, app.RFPButton = "TRUE");
               %         imshow(data_cell{1,1}{2,1})
                %else(app.ChannelButtonGroup, app.GFPButton = "TRUE");
                 %   imshow(data_cell{1,1}{3,1})
                  %  app.UIAxes = 
                    
                    
            %trans will be data_cell{1,1}{1,1} for plane 1, data_cell{1,1}{4(x+3?),1} for plane 2, etc for 7 planes
               %rfp will be data_cell{1,1}{2,1} for plane 1, data_cell{1,1}{5,1} for plane 2, etc for 7 planes
               %gfp will be data_cell{1,1}{3,1} for plane 1, data_cell{1,1}{6,1} for plane 2, etc for 7 planes
                %p=plane so for data cell, can have 1+2p, 2+3p, etc
        end

        % Button pushed function: SelectCellButton
        function SelectCellButtonPushed(app, event)
            
            h = imrect
            h = imrect(app.UIAxes, [262 31 357 427])
            fcn = makeConstrainToRectFnc('imrect',get(app.UIAxes,'xmax'),get(app.UIAxes,'ylim'));
            setPositionConstrainFcn(h,fcn);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.photoAnalysis = uifigure;
            app.photoAnalysis.Name = 'Photo Analysis';
            app.ChannelButtonGroup = uibuttongroup(app.photoAnalysis);
            app.ChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelButtonGroupSelectionChanged, true);
            app.ChannelButtonGroup.Title = 'Channel';
            app.TransButton = uitogglebutton(app.ChannelButtonGroup);
            app.TransButton.Text = 'Trans';
            app.TransButton.Value = true;
            app.RFPButton = uitogglebutton(app.ChannelButtonGroup);
            app.RFPButton.Text = 'RFP';
            app.GFPButton = uitogglebutton(app.ChannelButtonGroup);
            app.GFPButton.Text = 'GFP';
            app.UIAxes = uiaxes(app.photoAnalysis);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLim = [0 1];
            app.UIAxes.CLimMode = 'manual';
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.ContrastSliderLabel = uilabel(app.photoAnalysis);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.photoAnalysis);
            app.ContrastSlider.MajorTicks = [0 100];
            app.ContrastSlider.MajorTickLabels = {'', ''};
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ImportFileButton = uibutton(app.photoAnalysis, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.TimeSliderLabel = uilabel(app.photoAnalysis);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.photoAnalysis);
            app.TimeSlider.Limits = [0 10];
            app.TimeSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.TimeSlider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '10'};
            app.TimeSlider.MinorTicks = [];
            app.SelectCellButton = uibutton(app.photoAnalysis, 'push');
            app.SelectCellButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCellButtonPushed, true);
            app.SelectCellButton.Text = 'Select Cell';
        end
    end

    methods (Access = public)

        % Construct app
        function app = imageProcessingProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.photoAnalysis)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.photoAnalysis)
        end
    end
end